classdef MAF_neuromodulation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DeleteButton               matlab.ui.control.Button
        setCsButton                matlab.ui.control.Button
        cell_type_txt              matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        rec_list_tree              matlab.ui.container.CheckBoxTree
        HHMMSSLabel                matlab.ui.control.Label
        YYMMDDLabel                matlab.ui.control.Label
        CellidDropDownLabel        matlab.ui.control.Label
        NextButton                 matlab.ui.control.Button
        PrevButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        CellInteractionPanel       matlab.ui.container.Panel
        FiringRatePropertiesPanel  matlab.ui.container.Panel
        AxISI                      matlab.ui.control.UIAxes
        AxAutoProb                 matlab.ui.control.UIAxes
        AxWav                      matlab.ui.control.UIAxes
        CellidDropDown             matlab.ui.control.DropDown
        ModulationPanel            matlab.ui.container.Panel
        TagsTreeLabel              matlab.ui.control.Label
        TagsTree                   matlab.ui.container.CheckBoxTree
        TuningPanel                matlab.ui.container.Panel
        AlignmentDropDown          matlab.ui.control.DropDown
        AlignmentDropDownLabel     matlab.ui.control.Label
        ModulationButtonGroup      matlab.ui.container.ButtonGroup
        TongueButton               matlab.ui.control.RadioButton
        EyeButton                  matlab.ui.control.RadioButton
        AxMod315                   matlab.ui.control.UIAxes
        AxMod270                   matlab.ui.control.UIAxes
        AxMod225                   matlab.ui.control.UIAxes
        AxMod000                   matlab.ui.control.UIAxes
        AxMod180                   matlab.ui.control.UIAxes
        AxMod045                   matlab.ui.control.UIAxes
        AxMod090                   matlab.ui.control.UIAxes
        AxMod135                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        elec                    % electrode properties
        mpm                     % mpm values (n_recs x 1 double)
        sess_name               % session name (string)
        sess_path               % session path (string)
        rec_path                % recs path (n_recs x 1 cell)
        rec_list                % list of all recs (n_recs x 1 cell)
        units_path              % path to session units (string)
        cell_bndl;              % bundle of cells (nx1 double)
        cell_cson;              % cell id of the cs-on (nx1 double)
        cell_type;              % type of cells (nx1 double)
        cell_list;              % cell names (nx1 cell)
        neuralProp;             % Neural Properties of current cell
        csOnData;               % CS On Data of current cell
        expParams;              % EXPERIMENT PARAMS of current cell
        sacAll;                 % SACS_ALL_DATA of current cell
        lickAll;                % LICKS_ALL_DATA of current cell
        tags;                   % sacs or licks tag based on mod
        cross_probs;            % cross probs of cells
        flag_cell_changed;      % nx1 flag of changed cells
    end

    properties (Access = private)
        sess_meta_data          % session metadata (table)
        rec_meta_data           % recs metadata (n_recs x 1 cell)
        AxModTun;                           
        pltSS;
        pltCS;
        pltVisual;
        pltOnset;
        pltOffset;
        pltPolar;
        pltPolarStd;
        pltPolarDir;
        pltSSWav;
        pltCSWav;
        pltACorr;
        pltXCorr;
        pltISI;
        pltFr;
        pltVm;
        ch_map_txt;
        ax_cross;
    end

    methods (Access = private)

        function init_gui(app)
            initModPlots(app)
            initNeuroPropPlots(app)
        end

        function updateNeuroProp(app)

            delete(app.ch_map_txt);

            waveform = app.neuralProp.waveform;
            ss_wave = waveform.ss_wave;
            cs_wave = waveform.cs_wave;
            sample_rate = 3e4;

            x_ = waveform.ch_map.x * 4;
            y_ = waveform.ch_map.y * 100;

            if not(isnan(cs_wave))
                n_ch = size(cs_wave,1);
                n_sig = length(cs_wave);
                wave_ch = waveform.cs_wave_ch;
            elseif not(isnan(ss_wave))
                n_ch = size(ss_wave,1);
                n_sig = length(ss_wave);
                wave_ch = waveform.ss_wave_ch;
            end

            x = x_(wave_ch+1);
            y = y_(wave_ch+1);
            ch_num = waveform.ch_map.map(wave_ch+1);

            span_ind = (0:n_sig-1)/sample_rate;
            span_group_ = repmat([span_ind,nan],n_ch,1);
            span_group = reshape((span_group_*1e3+x)',1,n_ch*(n_sig+1));

            if not(ismember('CS',app.cell_type{app.CellidDropDown.Value}))
                ss_wave_ = [ss_wave, nan(n_ch,1)];
                ss_wave_group = reshape((ss_wave_+y)',1,n_ch*(n_sig+1));
                set(app.pltSSWav,'XData',span_group,'YData',ss_wave_group);
            end

            CS_types = {'PC','CS'};
            flag_cs = any(ismember(CS_types,...
                app.cell_type{app.CellidDropDown.Value}));
            if flag_cs
                cs_wave_ = [cs_wave, nan(n_ch,1)];
                cs_wave_group = reshape((cs_wave_+y)',1,n_ch*(n_sig+1));
                set(app.pltCSWav,'XData',span_group,'YData',cs_wave_group);
            else
                set(app.pltCSWav,'XData',nan,'YData',nan);
            end

            ch_map = arrayfun(@num2str, ch_num+1,'UniformOutput', 0);

            app.ch_map_txt =  text(app.AxWav, x-1,y,ch_map);

            ss_time = app.neuralProp.SS_time;
            ss_isi = diff(ss_time);
            ss_isi(ss_isi<0) = [];
            ss_isi_min = 0;
            ss_isi_max = .1;
            ss_isi_binNum = 50;
            ss_isi_edges = linspace(ss_isi_min, ss_isi_max, ss_isi_binNum);

            histogram(app.AxISI,ss_isi, ss_isi_edges,'Normalization',...
                'pdf', 'DisplayStyle', 'bar',...
                'EdgeColor', 'none', 'FaceColor', 'b')

            ss_autocorr_span = ...
                app.neuralProp.Corr_data_SS_inds_span;
            ss_autocorr = ...
                app.neuralProp.Corr_data_SS_SSxSS_AUTO;
            ss_autocorr(end/2) = nan;
            cs_ss_xcorr = ...
                app.neuralProp.Corr_data_CS_CSxSS_AUTO;

            set(app.pltACorr, 'XData',ss_autocorr_span, ...
                'YData',ss_autocorr);
            if flag_cs
                set(app.pltXCorr, 'XData',ss_autocorr_span, ...
                    'YData',cs_ss_xcorr);
            else
                set(app.pltXCorr, 'XData',nan, ...
                    'YData',nan);
            end
        end

        function updateMod(app)

            resetModPlots(app)

            tag_id = app.tags;
            alignment = app.AlignmentDropDown.Value;

            % 1: Eye, 2: Tongue
            switch(app.ModulationButtonGroup.SelectedObject.Text)
                case('Eye')
                    tags_ = app.expParams.sac_tag_list{1};
                    ang_step = 45;
                    ang_values   = (0) : ang_step : (360 - ang_step);
                    time_ind = 1:500;
                    length_trace = length(time_ind);
                    inds_span = time_ind-250;
                    range_SS_Firing = [0,200];
    
                    data_dir = buildSacData(app, tag_id, alignment);
                    CS_on_data = JSP_CS_on_analysis_fr(app.sacAll,tags_);
                    app.AxModTun.ThetaZeroLocation = 'right';
                    app.AxModTun.ThetaDir = 'counterclockwise';
                    thetaticks(app.AxModTun,[0:45:360])
                    thetalim(app.AxModTun,[0 360]);
                case('Tongue')
                    tags_ = app.expParams.lick_tag_list{1};

                    ang_step = 45;
                    ang_values   = -90 : ang_step : 90;
                    time_ind = 1:500;
                    length_trace = length(time_ind);
                    inds_span = time_ind-250;
                    range_SS_Firing = [0,300];
                    
                    data_dir = buildLickData(app, tag_id, alignment);
                    CS_on_data = PGH_CS_on_analysis(app.lickAll,tags_,tag_id);
                    app.AxModTun.ThetaZeroLocation = 'top';
                    app.AxModTun.ThetaDir = 'clockwise';
                    thetaticks(app.AxModTun,[-90 -45 0 45 90 ])
                    thetaticklabels(app.AxModTun,{'-90', '-45', '0','45', '90'})
                    thetalim(app.AxModTun,[-90 90]);
            end

            if isempty(app.tags)
                return
            end

            num_dir = numel(ang_values);

            axs = [app.AxMod000,...
                app.AxMod045,...
                app.AxMod090,...
                app.AxMod135,...
                app.AxMod180,...
                app.AxMod225,...
                app.AxMod270,...
                app.AxMod315];

            % Plot data, Loop over dirs
            for counter_dir = 1 : num_dir

                if isempty(data_dir(counter_dir).SS)
                    axs(counter_dir).XAxis.Visible = 'off';
                    axs(counter_dir).YAxis(1).Visible = 'off';
                    axs(counter_dir).YAxis(2).Visible = 'off';
                    continue;
                end

                train_data_logic_SS_ = data_dir(counter_dir).SS(time_ind,:)';
                firing_SS_ = mean(data_dir(counter_dir).SS(:,:),2) * 1000;
                firing_SS_ = ESN_smooth(firing_SS_);
                firing_SS_(firing_SS_<0)=0;
                if length(firing_SS_) == 1
                    firing_SS_ = zeros(1,length(data_dir(counter_dir).SS(:,:)));
                end
                firing_SS_ = firing_SS_(time_ind);
                train_data_logic_CS_ = data_dir(counter_dir).CS(time_ind,:)';

                % plot SS raster
                [x_axis_SS_, y_axis_SS_] = ESN_raster_plot_axes( ...
                    train_data_logic_SS_, inds_span, 0.5);
                set(app.pltSS(counter_dir), 'XData', x_axis_SS_(:), ...
                    'YData', y_axis_SS_(:));

                % plot CS raster
                [x_axis_CS_, y_axis_CS_] = ESN_raster_plot_axes( ...
                    train_data_logic_CS_, inds_span, 1);
                set(app.pltCS(counter_dir), 'XData', x_axis_CS_(:), ...
                    'YData', y_axis_CS_(:));

                num_trial = length(data_dir(counter_dir).time_onset);
                switch(app.ModulationButtonGroup.SelectedObject.Text)
                    case('Eye')
                        % Visual raster
                        time_visual_shifted = (data_dir...
                            (counter_dir).time_visual - data_dir...
                            (counter_dir).(sprintf('time_%s',alignment))).*1000;
                        time_visual_shifted = ESN_Round(time_visual_shifted);
                        idx_visual_shifted = time_visual_shifted + length_trace/2;
                        train_data_logic_visual = repmat...
                            (1:length_trace,num_trial,1) == idx_visual_shifted';
                        [x_axis_visual_, y_axis_visual_] =...
                            ESN_raster_plot_axes(train_data_logic_visual, inds_span, 1);
                        set(app.pltVisual(counter_dir), 'XData', x_axis_visual_(:), ...
                            'YData', y_axis_visual_(:));
                    case('Tongue')
                        % Dmax raster
                        time_dmax_shifted = (data_dir...
                            (counter_dir).time_dmax - data_dir...
                            (counter_dir).(sprintf('time_%s',alignment))).*1000;
                        time_dmax_shifted = ESN_Round(time_dmax_shifted);
                        idx_dmax_shifted = time_dmax_shifted + length_trace/2;
                        train_data_logic_dmax = repmat(1:length_trace,num_trial,1) == idx_dmax_shifted';
                        [x_axis_dmax_, y_axis_dmax_] = ESN_raster_plot_axes(train_data_logic_dmax, inds_span, 1);
                        set(app.pltVisual(counter_dir), 'XData', x_axis_dmax_(:), ...
                            'YData', y_axis_dmax_(:));
                end

                % Onset raster
                time_onset_shifted = (data_dir(counter_dir).time_onset - data_dir(counter_dir).(sprintf('time_%s',alignment))).*1000;
                time_onset_shifted = ESN_Round(time_onset_shifted);
                idx_onset_shifted = time_onset_shifted + length_trace/2;
                train_data_logic_onset = repmat(1:length_trace,num_trial,1) == idx_onset_shifted';
                [x_axis_onset_, y_axis_onset_] = ESN_raster_plot_axes(train_data_logic_onset, inds_span, 1);
                set(app.pltOnset(counter_dir), 'XData', x_axis_onset_(:), ...
                    'YData', y_axis_onset_(:));

                % Offset raster
                time_offset_shifted = (data_dir(counter_dir).time_offset - data_dir(counter_dir).(sprintf('time_%s',alignment))).*1000;
                time_offset_shifted = ESN_Round(time_offset_shifted);
                idx_offset_shifted = time_offset_shifted + length_trace/2;
                train_data_logic_offset = repmat(1:length_trace,num_trial,1) == idx_offset_shifted';
                [x_axis_offset_, y_axis_offset_] = ESN_raster_plot_axes(train_data_logic_offset, inds_span, 1);
                set(app.pltOffset(counter_dir), 'XData', x_axis_offset_(:), ...
                    'YData', y_axis_offset_(:));
                ylim(axs(counter_dir),[0,num_trial+.5]);

                % plot SS rate
                yyaxis(axs(counter_dir),'right');
                set(app.pltFr(counter_dir), 'XData', inds_span(:), 'YData', firing_SS_);

                switch(app.ModulationButtonGroup.SelectedObject.Text)
                    case('Eye')
                        % Plot onset vm
                        vm_ = data_dir(counter_dir).eye_vm(time_ind,:);
                        vm_ = nanmean(vm_,2);
                        set(app.pltVm(counter_dir), 'XData', inds_span, 'YData', vm_/3);
                    case('Tongue')
                        % Plot dm
                        dm_ = data_dir(counter_dir).tongue_dm(time_ind,:);
                        dm_ = nanmean(dm_,2);
                        set(app.pltVm(counter_dir), 'XData',inds_span, 'YData', dm_*10)
                end

                ylim(axs(counter_dir),range_SS_Firing);
                xlim(axs(counter_dir),[inds_span(1),inds_span(end)]);

            end

            fr_amplitude = CS_on_data.CS_fr_avg;

            vonMises_std = CS_on_data.vonMises_std;
            CS_ang_avg = CS_on_data.CS_ang_avg;
            CS_rho_avg = CS_on_data.CS_rho_avg;
            std_curv_ang = (CS_on_data.CS_ang_avg-vonMises_std) : 2 : (CS_on_data.CS_ang_avg+vonMises_std);
            std_curv_amp = repmat(CS_rho_avg, length(std_curv_ang), 1);

            plot_data_amp_mean = [fr_amplitude,...
                fr_amplitude(1), nan]';
            plot_data_deg_mean = [ang_values, ang_values(1), nan]';

            set(app.pltPolar, 'ThetaData', ...
                deg2rad(plot_data_deg_mean), 'RData', plot_data_amp_mean);
            set(app.pltPolarStd, 'ThetaData', ...
                deg2rad(std_curv_ang), 'RData', std_curv_amp);
            set(app.pltPolarDir, 'ThetaData', ...
                [0 deg2rad(CS_ang_avg)], 'RData', [0 CS_rho_avg]);

        end

        function initModPlots(app)

            axs = [app.AxMod000,...
                app.AxMod045,...
                app.AxMod090,...
                app.AxMod135,...
                app.AxMod180,...
                app.AxMod225,...
                app.AxMod270,...
                app.AxMod315];

            app.pltCS = [];
            app.pltSS = [];
            app.pltOffset = [];
            app.pltOnset = [];

            % Figure parameters
            Line_Color = lines(7);
            color_SS = Line_Color(1,:);
            color_offset = Line_Color(2,:);
            color_visual = Line_Color(3,:);
            color_onset = Line_Color(4,:);
            color_vm = Line_Color(5,:);
            color_SS_firing = [0    0.3    0.5];
            color_CS = Line_Color(7,:);

            range_SS_Firing = [0,200];

            % Plot data, Loop over dirs
            for counter_dir = 1 : 8

                cla(axs(counter_dir));
                hold(axs(counter_dir),'on');

                app.pltSS(counter_dir) = plot(axs(counter_dir), ...
                    nan, nan, 'Color',color_SS,'LineWidth',1);
                app.pltCS(counter_dir) = plot(axs(counter_dir), ...
                    nan, nan, 'Marker','.','MarkerSize',10,...
                    'LineWidth',5,'Color',color_CS);
                app.pltVisual(counter_dir) = plot(axs(counter_dir), ...
                    nan, nan, 'LineWidth', 2, 'Color', color_visual);
                app.pltOnset(counter_dir) = plot(axs(counter_dir), ...
                    nan, nan, 'LineWidth', 2, 'Color', color_onset);
                app.pltOffset(counter_dir) = plot(axs(counter_dir), ...
                    nan, nan, 'LineWidth', 1, 'Color', color_offset);

                yyaxis(axs(counter_dir),'right');
                set(axs(counter_dir), 'YColor', color_SS_firing);

                app.pltFr(counter_dir) = plot(axs(counter_dir), ...
                    nan, nan, 'LineWidth', 2, 'Color', color_SS_firing);
                ylim(axs(counter_dir),range_SS_Firing);

                if (counter_dir==1) || (counter_dir==2) || (counter_dir==8)
                    ylabel(axs(counter_dir),'SS Firing (spk/s)')
                    yticks(axs(counter_dir), [0 50 100 150 200])
                    yticklabels(axs(counter_dir), ...
                        {'0','','100', '', '200'})
                else
                    yticks(axs(counter_dir), [0 50 100 150 200])
                    yticklabels(axs(counter_dir), ...
                        {' ',' ',' ', ' ', ' '})
                    ylabel(axs(counter_dir),' ')
                end

                % vm/3 to make the axis [0,200]
                app.pltVm(counter_dir) = area(axs(counter_dir),nan, nan,...
                    'FaceColor', color_vm,'FaceAlpha',.5);

                if (counter_dir==8) || (counter_dir == 7) || (counter_dir == 6)
                    xlabel(axs(counter_dir),'Time from onset (ms)');
                else
                    xlabel(axs(counter_dir),' ');
                end
                MAF_Beautify_Ax(axs(counter_dir), 8)
            end

            app.AxModTun = polaraxes(app.TuningPanel);
            axp = app.AxModTun;
            cla(axp);
            hold(axp,'on');
            app.pltPolar = polarplot(axp, nan, nan,...
                'LineWidth', 1, 'Color', color_CS);
            app.pltPolarStd = polarplot(axp, nan, nan, '-', ...
                'LineWidth', 1.5, 'Color', color_CS);
            app.pltPolarDir = polarplot(axp, nan, nan, '-', ...
                'LineWidth', 1.5, 'Color', color_CS);

            thetaticks(axp, 0:45:315);
            thetaticklabels(axp,{'0','','90','', '180','','270', ''});
            MAF_Beautify_Ax(axp, 8)

        end

        function initNeuroPropPlots(app)
            app.pltSSWav = plot(app.AxWav,nan,nan,'b','LineWidth',2);
            hold(app.AxWav,'on')
            app.pltCSWav = plot(app.AxWav,nan,nan,'r','LineWidth',2);
            app.ch_map_txt = text(app.AxWav,0,0,'');
            set(app.AxWav, 'xcolor', 'w');
            set(app.AxWav, 'ycolor', 'w');
            set(app.AxWav, 'xtick', []);
            set(app.AxWav, 'ytick', []);

            app.pltISI = histogram(app.AxISI,nan);
            hold(app.AxISI,'on')

            app.pltACorr = plot(app.AxAutoProb,...
                nan,nan,'b',...
                'LineWidth',2);
            hold(app.AxAutoProb,'on')
            app.pltXCorr = plot(app.AxAutoProb,...
                nan,nan,'r',...
                'LineWidth',2);
            MAF_Beautify_Ax(app.AxWav)
            MAF_Beautify_Ax(app.AxISI)
            MAF_Beautify_Ax(app.AxAutoProb)
        end

        function resetModPlots(app)
            for counter_dir = 1:8
                set(app.pltSS(counter_dir), 'XData', nan, 'YData', nan);
                set(app.pltCS(counter_dir), 'XData', nan, 'YData', nan);
                set(app.pltVisual(counter_dir), 'XData', nan, 'YData', nan);
                set(app.pltOnset(counter_dir), 'XData', nan, 'YData', nan);
                set(app.pltOffset(counter_dir), 'XData', nan, 'YData', nan);
                set(app.pltFr(counter_dir), 'XData', nan, 'YData', nan);
                set(app.pltVm(counter_dir), 'XData', [], 'YData', []);
            end
            set(app.pltPolar, 'XData', nan, 'YData', nan);
            set(app.pltPolarDir, 'XData', nan, 'YData', nan);
            set(app.pltPolarStd, 'XData', nan, 'YData', nan);
        end

        function sac_data_dir = buildSacData(app,tag_id,alignment)

            SACS_ALL_DATA = app.sacAll;
            ang_step     = 45;
            ang_edges    = (0 - (ang_step/2)) : ang_step : (360 + (ang_step/2));
            ang_values   = (0) : ang_step : (360 - ang_step);
            length_trace = 500;

            % Build sac_data
            idx_sacs    = ismember(SACS_ALL_DATA.tag, tag_id);
            idx_sacs    = idx_sacs & SACS_ALL_DATA.validity;

            sac_data.time    = SACS_ALL_DATA.(['time_',alignment])(:,idx_sacs);
            sac_data.SS      = SACS_ALL_DATA.(['neuro_SS_',alignment])(:,idx_sacs);
            sac_data.CS      = SACS_ALL_DATA.(['neuro_CS_',alignment])(:,idx_sacs);
            sac_data.eye_vx  = SACS_ALL_DATA.(['eye_vx_',alignment])(:,idx_sacs);
            sac_data.eye_vy  = SACS_ALL_DATA.(['eye_vy_',alignment])(:,idx_sacs);

            sac_data.eye_vm = sqrt(sac_data.eye_vx.^2 + sac_data.eye_vy.^2);

            sac_data.eye_r_px_onset   = SACS_ALL_DATA.eye_r_px_onset(:,idx_sacs);
            sac_data.eye_r_py_onset   = SACS_ALL_DATA.eye_r_py_onset(:,idx_sacs);

            sac_data.visual_px_offset = SACS_ALL_DATA.visual_px_offset(:,idx_sacs);
            sac_data.visual_py_offset = SACS_ALL_DATA.visual_py_offset(:,idx_sacs);

            sac_data.time_onset   = SACS_ALL_DATA.time_onset (     :,idx_sacs);
            sac_data.time_offset  = SACS_ALL_DATA.time_offset(     :,idx_sacs);
            sac_data.time_visual  = SACS_ALL_DATA.time_visual(     :,idx_sacs);

            sac_data.time_diff_visual_onset = sac_data.time_onset - sac_data.time_visual;
            sac_data.time_diff_onset_offset = sac_data.time_offset - sac_data.time_onset;

            % Build sac_data_dir
            sac_data.delta_x = sac_data.visual_px_offset - sac_data.eye_r_px_onset;
            sac_data.delta_y = sac_data.visual_py_offset - sac_data.eye_r_py_onset;
            sac_data.visual_ang = wrapTo360(atan2d(sac_data.delta_y, sac_data.delta_x));
            sac_data.visual_ang_bin = discretize(sac_data.visual_ang, ang_edges);
            last_bin_id = length(ang_edges) - 1;
            sac_data.visual_ang_bin(sac_data.visual_ang_bin == last_bin_id) = 1; % wrap the circle around
            % 1: 0deg % 2: 45deg % 3: 90deg % 4: 135deg % 5: 180deg % 6: 225deg % 7: 270deg % 8: 315deg

            if length(ang_values) ~= 8
                error('plot_single_session_modulation: length ang_values is not 8. Please modify the code.')
            end
            if length_trace ~= 500
                error('sac_modulation_index: length_trace is not 500. Please modify the code.')
            end

            field_names_sac_data = fieldnames(sac_data);
            sac_data_dir = struct;
            for counter_dir = 1 : 8
                for counter_field = 1 : length(field_names_sac_data)
                    field_name = field_names_sac_data{counter_field};
                    idx_ang = (sac_data.visual_ang_bin == counter_dir);
                    sac_data_dir(counter_dir).(field_name) = sac_data.(field_name)(:,idx_ang);
                end
            end
        end

        function load_data(app)
            app.sess_meta_data = readtable([app.sess_path...
                app.sess_name '.xls']);

            app.units_path = [app.sess_path 'units' filesep];

            load([app.units_path app.sess_name '.mat'],'cross_prob');

            app.cross_probs = cross_prob;

            app.rec_list = app.sess_meta_data.folder_name(logical...
                (app.sess_meta_data.ephys .* app.sess_meta_data.eye));

            app.mpm = app.sess_meta_data.MPM(logical...
                (app.sess_meta_data.ephys .* app.sess_meta_data.eye));

            elec_id_ = app.sess_meta_data.elec(logical...
                (app.sess_meta_data.ephys .* app.sess_meta_data.eye));
            elec_id = elec_id_(1);

            n_recs = length(app.rec_list);

            app.rec_path = cell(n_recs,1);
            recs_bndl = cell(n_recs,1);
            recs_type = cell(n_recs,1);
            recs_cson = cell(n_recs,1);

            for counter_recs = 1:n_recs
                current_rec = app.rec_list{counter_recs};
                rec_name = regexprep(current_rec(3:end),'-','');
                app.rec_path{counter_recs} = ...
                    [app.sess_path current_rec filesep];

                % read rec_meta_data
                if not(isfile([app.rec_path{counter_recs} rec_name '.xls']))
                    error('No rec Metadata!')
                end

                app.rec_meta_data{counter_recs} = ...
                    readtable([app.rec_path{counter_recs} rec_name '.xls']);
                bndl_ = app.rec_meta_data{counter_recs}.cell;
                if sum(isnan(bndl_)) > 0
                    error('Invalid Bundling!')
                end
                if iscell(app.rec_meta_data{counter_recs}.type)
                    type_ = app.rec_meta_data{counter_recs}.type;
                else
                    type_ = repmat({''},...
                        numel(app.rec_meta_data{counter_recs}.type),1);
                end
                recs_bndl{counter_recs} = bndl_;
                recs_type{counter_recs} = type_;
                if ismember('csOn',...
                        app.rec_meta_data...
                        {counter_recs}.Properties.VariableNames)
                    recs_cson{counter_recs} = ...
                        app.rec_meta_data{counter_recs}.csOn;
                else
                    recs_cson{counter_recs} = ...
                        nan(size(bndl_));
                end

            end

            switch(elec_id)
                case(1)
                    app.elec.x = 16*repmat([0,2,1,3],1,16);
                    app.elec.y = 20*repelem(1:32,2);
                    app.elec.model = 'Cambridge Checkerboard (M1)';
                case(2)
                    app.elec.x = zeros(1,64);
                    app.elec.y = 20*(1:64);
                    app.elec.model = 'Cambridge Linear (M2)';
                case(3)
                    app.elec.x = 3*[sqrt(3),sqrt(3),sqrt(3)/2,3*sqrt(3)/2];
                    app.elec.y = 6*[3,2,3/2,3/2];
                    app.elec.model = 'tetrode';
                case(4)
                    app.elec.x = 3*[0, sqrt(3), sqrt(3)/2, sqrt(3),...
                        3*sqrt(3)/2, sqrt(3), 2*sqrt(3)];
                    app.elec.y = 6*[3, 0, 3/2, 2, 3/2, 3, 3];
                    app.elec.model = 'heptode';
            end

            cell_list_ = dir([app.sess_path 'units' filesep app.sess_name '*']);
            cell_list_([cell_list_.isdir] == 0) = [];
            app.cell_list = {cell_list_.name};

            app.cell_bndl = cell2mat(cellfun(@(x) str2double(x(18:20)),...
                app.cell_list,'UniformOutput',false));

            n_cells = numel(app.cell_bndl);
            app.cell_type = cell(n_cells,1);
            bndls_ = cell2mat(recs_bndl);
            types_ = cat(1,recs_type{:});
            csons_ = cell2mat(recs_cson);

            for counter_cell = 1:n_cells
                ind = find(bndls_ == app.cell_bndl(counter_cell),1);
                app.cell_type{counter_cell} = types_{ind};
                app.cell_cson(counter_cell) = csons_(ind);
            end

            % type changed, CS on Selected
            app.flag_cell_changed = zeros(n_cells,1,'logical');

        end

        function updateCellInter(app)

            for counter_ax = 1:numel(app.ax_cross)
                delete(app.ax_cross{counter_ax})
            end

            cell_selected_ = app.CellidDropDown.Value;

            cross_probs_ = app.cross_probs(:,cell_selected_);

            ind_cross = find(cellfun(@isempty,cross_probs_) == 0);
            n_cross = numel(ind_cross);

            app.ax_cross = [];

            for counter_cross = 1:n_cross
                app.CellInteractionPanel.AutoResizeChildren = 'off';
                app.ax_cross{counter_cross} = subplot(...
                    ceil(n_cross/2),2,counter_cross,...
                    'Parent',app.CellInteractionPanel);
                hold(app.ax_cross{counter_cross},'on');
                current_cross_probs = ...
                    cross_probs_{ind_cross(counter_cross)};
                color_order = {'b','r','m','k'};
                for counter_plot = 1:4
                    plot(app.ax_cross{counter_cross},...
                        current_cross_probs(5,:)*1e3,...
                        current_cross_probs(counter_plot,:),...
                        'LineWidth',2,'Color',color_order{counter_plot});
                    if counter_cross == n_cross
                        xlabel(app.ax_cross{counter_cross},'time (ms)');
                    else
                        xticks(app.ax_cross{counter_cross},{});
                    end

                    if app.cell_cson(cell_selected_) ==...
                            app.cell_bndl(ind_cross(counter_cross))
                        color_ = 'r';
                    else
                        color_ = 'k';
                    end

                    title(app.ax_cross{counter_cross},...
                        [app.cell_list{ind_cross(counter_cross)}(8:20),...
                        ' (',app.cell_type{ind_cross(counter_cross)} ,')'],...
                        'Interpreter','none','Color',color_);
                end
            end
        end


        %% function buildLickData
        function lick_data_dir = buildLickData(app,tag_id,alignment)

            length_trace = 500;
            ang_step     = 45;
            ang_edges    = -90 - ang_step/2 : ang_step : 90 + ang_step/2 ;
            ang_values   = -90 : ang_step : 90;

            LICKS_ALL_DATA = app.lickAll;

            % Build lick_data
            idx_licks    = ismember(LICKS_ALL_DATA.tag, tag_id);
            idx_licks    = idx_licks & LICKS_ALL_DATA.validity;

            lick_data.time    = LICKS_ALL_DATA.(['time_',alignment])(:,idx_licks);
            lick_data.SS      = LICKS_ALL_DATA.(['neuro_SS_',alignment])(:,idx_licks);
            lick_data.CS      = LICKS_ALL_DATA.(['neuro_CS_',alignment])(:,idx_licks);
            lick_data.tongue_dm  = LICKS_ALL_DATA.(['tongue_dm_',alignment])(:,idx_licks);
            lick_data.tongue_vm  = LICKS_ALL_DATA.(['tongue_vm_',alignment])(:,idx_licks);
            lick_data.tongue_ang = LICKS_ALL_DATA.(['tongue_ang_',alignment])(:,idx_licks);

            lick_data.tongue_dm_max =  LICKS_ALL_DATA.tongue_dm_max(    :,idx_licks);
            lick_data.tongue_vm_max =  LICKS_ALL_DATA.tongue_vm_max(    :,idx_licks);
            lick_data.tongue_vm_min =  LICKS_ALL_DATA.tongue_vm_min(    :,idx_licks);
            lick_data.tongue_ang_max =  LICKS_ALL_DATA.tongue_ang_max(    :,idx_licks);

            lick_data.time_onset   = LICKS_ALL_DATA.time_onset (     :,idx_licks);
            lick_data.time_vmax   = LICKS_ALL_DATA.time_vmax (     :,idx_licks);
            lick_data.time_dmax  = LICKS_ALL_DATA.time_dmax   (     :,idx_licks);
            lick_data.time_vmin   = LICKS_ALL_DATA.time_vmin (     :,idx_licks);
            lick_data.time_offset  = LICKS_ALL_DATA.time_offset(     :,idx_licks);

            lick_data.time_diff_onset_offset = lick_data.time_offset - lick_data.time_onset;
            lick_data.time_diff_onset_dmax= lick_data.time_dmax - lick_data.time_onset;
            lick_data.time_diff_ofFset_dmax= lick_data.time_offset - lick_data.time_onset;

            % Build lick_data_dir
            lick_data.tongue_ang_bin = discretize(lick_data.tongue_ang_max, ang_edges);

            % 1: +/-180deg % 2: -135deg % 3: -90deg % 4: -45deg % 5: 0deg % 6: 45deg % 7: 90deg % 8: 135deg

            if length(ang_values) ~= 5
                error('plot_single_session_modulation: length ang_values is not 5. Please modify the code.')
            end
            if length_trace ~= 500
                error('lick_modulation_index: length_trace is not 500. Please modify the code.')
            end

            field_names_lick_data = fieldnames(lick_data);
            lick_data_dir = struct;
            for counter_dir = 1 : length(ang_values)
                for counter_field = 1 : length(field_names_lick_data)
                    field_name = field_names_lick_data{counter_field};
                    idx_ang = (lick_data.tongue_ang_bin == counter_dir);
                    lick_data_dir(counter_dir).(field_name) = lick_data.(field_name)(:,idx_ang);
                end
            end
        end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path_data_monkey_sorted, session)

        if ~strcmp(path_data_monkey_sorted(end), filesep)
            path_data_monkey_sorted = [path_data_monkey_sorted filesep];
        end

        app.sess_path = [path_data_monkey_sorted, session(1:7), filesep,...
            session, filesep];

        app.sess_name = regexprep(session(3:end),'-','');

        app.YYMMDDLabel.Text = app.sess_name;

        init_gui(app)
        load_data(app)

        n_recs = numel(app.rec_list);

        for counter_rec = 1:n_recs
            current_rec = app.rec_list{counter_rec};
            text_ = [current_rec ' (' num2str(app.mpm(counter_rec)) ')'];
            uitreenode(app.rec_list_tree,'Text',text_);
        end

        cell_ch_idd = cellfun(@(x) x(15:20),app.cell_list,...
            'UniformOutput',false);

        app.CellidDropDown.Items = cell_ch_idd';
        app.CellidDropDown.ItemsData = 1:numel(cell_ch_idd);
        CellidDropDownValueChanged(app, []);

        end

        % Value changed function: CellidDropDown
        function CellidDropDownValueChanged(app, event)
        d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Loading ...');
        cell_selected_ind = app.CellidDropDown.Value;
        cell_selected_ = app.cell_list{cell_selected_ind};
        try
            load([app.units_path cell_selected_ ...
                filesep cell_selected_ '.mat'],...
                'Neural_Properties','SACS_ALL_DATA',...
                'LICKS_ALL_DATA','EXPERIMENT_PARAMS');
            app.lickAll = LICKS_ALL_DATA;
        catch
            load([app.units_path cell_selected_ ...
                filesep cell_selected_ '.mat'],...
                'Neural_Properties','SACS_ALL_DATA','EXPERIMENT_PARAMS');
            app.lickAll = [];
        end

        d.Value = .8;
        app.neuralProp = Neural_Properties;
        app.sacAll = SACS_ALL_DATA;
        app.expParams = EXPERIMENT_PARAMS;

        app.ModulationButtonGroup.SelectedObject.Value = 1;
        ModulationButtonGroupSelectionChanged(app);

        recs = app.rec_list_tree.Children;
        app.rec_list_tree.CheckedNodes = ...
            recs(app.expParams.rec_info.rec_flag);

        cross_probs_ = app.cross_probs(:,cell_selected_ind);

        ind_cross = find(cellfun(@isempty,cross_probs_) == 0);

        current_cell_type = app.cell_type...
            {cell_selected_ind};

        app.cell_type_txt.Value = current_cell_type;

        app.HHMMSSLabel.Text = cell_selected_(8:13);

        CS_types = {'PC','CS'};

        % enable if not in CS_types and has CS_types interaction
        app.setCsButton.Enable = logical(...
            any(ismember(CS_types,app.cell_type(ind_cross)))*...
            not(any(ismember(CS_types,current_cell_type))));

        updateNeuroProp(app)
        updateMod(app)
        updateCellInter(app)
        close(d);
        end

        % Value changed function: AlignmentDropDown
        function AlignmentDropDownValueChanged(app, event)
        updateMod(app);
        end

        % Callback function: TagsTree
        function TagsTreeCheckedNodesChanged(app, event)
        checkedNodes_ = app.TagsTree.CheckedNodes;
        if isempty(checkedNodes_)
            app.tags = [];
        else
            checkedNodes = {checkedNodes_.Text};
            app.tags = cell2mat(cellfun(@(x) str2double(x(1:2)), ...
                checkedNodes,'UniformOutput',false ));
            app.tags(isnan(app.tags)) = [];
        end
        updateMod(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
        ind_current = app.CellidDropDown.Value;
        if ind_current == numel(app.CellidDropDown.Items)
            ind_next = 1;
        else
            ind_next = ind_current + 1;
        end
        app.CellidDropDown.Value = ind_next;
        CellidDropDownValueChanged(app, []);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
        ind_current = app.CellidDropDown.Value;
        if ind_current == 1
            ind_prev = numel(app.CellidDropDown.Items);
        else
            ind_prev = ind_current - 1;
        end
        app.CellidDropDown.Value = ind_prev;
        CellidDropDownValueChanged(app, []);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Saving ...');
        changed_ids = find(app.flag_cell_changed);
        n_cells = numel(changed_ids);

        % update recs meta_data
        n_recs = numel(app.rec_list);
        for counter_recs = 1:n_recs
            d.Value = counter_recs/n_recs;
            current_rec = app.rec_list{counter_recs};
            rec_name = regexprep(current_rec(3:end),'-','');
            rec_meta_data_ = app.rec_meta_data{counter_recs};
            rec_cells = rec_meta_data_.cell;
            if not(iscell(rec_meta_data_.type))
                rec_meta_data_.type = [];
                rec_meta_data_.type = repmat({''},...
                    numel(rec_cells),1);
            end
            rec_meta_data_.csOn = nan(size(rec_cells));
            for counter_cell = 1:n_cells
                current_cell = changed_ids(counter_cell);
                rec_meta_data_.type...
                    (rec_cells == app.cell_bndl(current_cell)) = ...
                    app.cell_type(current_cell);
                rec_meta_data_.csOn...
                    (rec_cells == app.cell_bndl(current_cell)) = ...
                    app.cell_cson(current_cell);
            end
            writetable(rec_meta_data_,...
                [app.rec_path{counter_recs} rec_name],...
                'FileType','spreadsheet');
        end
        close(d);

        end

        % Value changed function: cell_type_txt
        function cell_type_txtValueChanged(app, event)
        app.cell_type{app.CellidDropDown.Value} = ...
            app.cell_type_txt.Value;
        app.flag_cell_changed(app.CellidDropDown.Value) = 1;
        updateMod(app);
        updateNeuroProp(app);
        end

        % Button pushed function: setCsButton
        function setCsButtonPushed(app, event)
        cell_selected_ind = app.CellidDropDown.Value;

        cross_probs_ = app.cross_probs(:,cell_selected_ind);

        ind_cross = find(cellfun(@isempty,cross_probs_) == 0);

        current_cell_type = app.cell_type...
            {cell_selected_ind};

        app.cell_type_txt.Value = current_cell_type;

        CS_types = {'PC','CS'};
        ind_cross(not(...
            ismember(app.cell_type(ind_cross),CS_types))) = [];

        choices = ...
            app.cell_list(ind_cross);

        [selected_ind,~] = listdlg('PromptString','Select Cell',...
            'SelectionMode','single','ListString',choices);

        if isempty(selected_ind)
            app.cell_cson(cell_selected_ind) = nan;
        else
            app.cell_cson(cell_selected_ind) =...
                app.cell_bndl(ind_cross(selected_ind));
        end
        app.flag_cell_changed(cell_selected_ind) = 1;
        updateCellInter(app);
        end

        % Selection changed function: ModulationButtonGroup
        function ModulationButtonGroupSelectionChanged(app, event)
        selectedButton = app.ModulationButtonGroup.SelectedObject.Text;
        nodes_ = app.TagsTree.Children;
        nodes_.delete;
        switch(selectedButton)
            % eye
            case('Eye')
                tags_ = app.expParams.sac_tag_list{1};
                for counter_tag = 1:numel(tags_)
                    tag_name = [num2str(counter_tag), ' ', tags_{counter_tag}];
                    uitreenode(app.TagsTree,'Text',tag_name);
                end
                % tongue
            case('Tongue')
                tags_ = app.expParams.lick_tag_list{1};
                for counter_tag = 1:numel(tags_)
                    tag_name = [num2str(counter_tag), ' ', tags_{counter_tag}];
                    uitreenode(app.TagsTree,'Text',tag_name);
                end
        end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModulationPanel = uipanel(app.UIFigure);
            app.ModulationPanel.Title = 'Modulation';
            app.AxMod135 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod135, 'Z')
            app.AxMod090 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod090, 'Z')
            app.AxMod045 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod045, 'Z')
            app.AxMod180 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod180, 'Z')
            app.AxMod000 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod000, 'Z')
            app.AxMod225 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod225, 'Z')
            app.AxMod270 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod270, 'Z')
            app.AxMod315 = uiaxes(app.ModulationPanel);
            zlabel(app.AxMod315, 'Z')
            app.ModulationButtonGroup = uibuttongroup(app.ModulationPanel);
            app.ModulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModulationButtonGroupSelectionChanged, true);
            app.ModulationButtonGroup.Title = 'Modulation';
            app.EyeButton = uiradiobutton(app.ModulationButtonGroup);
            app.EyeButton.Text = 'Eye';
            app.EyeButton.Value = true;
            app.TongueButton = uiradiobutton(app.ModulationButtonGroup);
            app.TongueButton.Text = 'Tongue';
            app.AlignmentDropDownLabel = uilabel(app.ModulationPanel);
            app.AlignmentDropDownLabel.Text = 'Alignment';
            app.AlignmentDropDown = uidropdown(app.ModulationPanel);
            app.AlignmentDropDown.Items = {'visual', 'onset', 'vmax', 'offset', 'auditory'};
            app.AlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmentDropDownValueChanged, true);
            app.AlignmentDropDown.Value = 'onset';
            app.TuningPanel = uipanel(app.ModulationPanel);
            app.TagsTree = uitree(app.ModulationPanel, 'checkbox');
            app.TagsTree.Tooltip = {''};
            % Assign Checked Nodes
            app.TagsTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TagsTreeCheckedNodesChanged, true);
            app.TagsTreeLabel = uilabel(app.ModulationPanel);
            app.TagsTreeLabel.Text = 'Tags';
            app.CellidDropDown = uidropdown(app.UIFigure);
            app.CellidDropDown.ValueChangedFcn = createCallbackFcn(app, @CellidDropDownValueChanged, true);
            app.FiringRatePropertiesPanel = uipanel(app.UIFigure);
            app.FiringRatePropertiesPanel.Title = 'Firing Rate Properties';
            app.AxWav = uiaxes(app.FiringRatePropertiesPanel);
            title(app.AxWav, 'Waveform')
            xlabel(app.AxWav, 'time (ms)')
            ylabel(app.AxWav, 'uv')
            zlabel(app.AxWav, 'Z')
            app.AxAutoProb = uiaxes(app.FiringRatePropertiesPanel);
            title(app.AxAutoProb, 'Auto Probability')
            xlabel(app.AxAutoProb, 'time (ms)')
            ylabel(app.AxAutoProb, 'prob.')
            zlabel(app.AxAutoProb, 'Z')
            app.AxISI = uiaxes(app.FiringRatePropertiesPanel);
            title(app.AxISI, 'ISI dist.')
            xlabel(app.AxISI, 'ISI (ms)')
            ylabel(app.AxISI, 'prob.')
            zlabel(app.AxISI, 'Z')
            app.CellInteractionPanel = uipanel(app.UIFigure);
            app.CellInteractionPanel.Title = 'Cell Interaction';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CellidDropDownLabel = uilabel(app.UIFigure);
            app.CellidDropDownLabel.Text = 'Cell id:';
            app.YYMMDDLabel = uilabel(app.UIFigure);
            app.YYMMDDLabel.Text = 'YYMMDD';
            app.HHMMSSLabel = uilabel(app.UIFigure);
            app.HHMMSSLabel.Text = 'HHMMSS';
            app.rec_list_tree = uitree(app.UIFigure, 'checkbox');
            app.rec_list_tree.Enable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_';
            app.cell_type_txt = uieditfield(app.UIFigure, 'text');
            app.cell_type_txt.ValueChangedFcn = createCallbackFcn(app, @cell_type_txtValueChanged, true);
            app.setCsButton = uibutton(app.UIFigure, 'push');
            app.setCsButton.ButtonPushedFcn = createCallbackFcn(app, @setCsButtonPushed, true);
            app.setCsButton.Text = 'Set CS';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAF_neuromodulation_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end